classdef filter_edit_20180714_Eng < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Filteredit2018714designedbyFNagataUIFigure  matlab.ui.Figure
        filterSizeOddSpinnerLabel       matlab.ui.control.Label
        filterSizeOddSpinner            matlab.ui.control.Spinner
        StrideEditFieldLabel            matlab.ui.control.Label
        StrideEditField                 matlab.ui.control.NumericEditField
        PaddingSizeEditFieldLabel       matlab.ui.control.Label
        PaddingSizeEditField            matlab.ui.control.NumericEditField
        PoolingSizeOddEditFieldLabel    matlab.ui.control.Label
        PoolingSizeOddEditField         matlab.ui.control.NumericEditField
        ParametersSettingfor6PreTrainingofDCNNButton  matlab.ui.control.Button
        filterSizeOddSpinner_2Label     matlab.ui.control.Label
        filterSizeOddSpinner_2          matlab.ui.control.Spinner
        StrideEditField_2Label          matlab.ui.control.Label
        StrideEditField_2               matlab.ui.control.NumericEditField
        PaddingSizeEditField_2Label     matlab.ui.control.Label
        PaddingSizeEditField_2          matlab.ui.control.NumericEditField
        PoolingSizeOddEditField_2Label  matlab.ui.control.Label
        PoolingSizeOddEditField_2       matlab.ui.control.NumericEditField
        filterSizeOddSpinner_3Label     matlab.ui.control.Label
        filterSizeOddSpinner_3          matlab.ui.control.Spinner
        StrideEditField_3Label          matlab.ui.control.Label
        StrideEditField_3               matlab.ui.control.NumericEditField
        PaddingSizeEditField_3Label     matlab.ui.control.Label
        PaddingSizeEditField_3          matlab.ui.control.NumericEditField
        PoolingSizeOddEditField_3Label  matlab.ui.control.Label
        PoolingSizeOddEditField_3       matlab.ui.control.NumericEditField
        Layer2Label                     matlab.ui.control.Label
        Layer5Label                     matlab.ui.control.Label
        Layer8Label                     matlab.ui.control.Label
        Thisisnotappliedto7AdditionalTrainingofDNNLabel  matlab.ui.control.Label
        numFiltersEditFieldLabel        matlab.ui.control.Label
        numFiltersEditField             matlab.ui.control.NumericEditField
        numFiltersEditField_2Label      matlab.ui.control.Label
        numFiltersEditField_2           matlab.ui.control.NumericEditField
        numFiltersEditField_3Label      matlab.ui.control.Label
        numFiltersEditField_3           matlab.ui.control.NumericEditField
        TrainingparametersofconvolutionlayersandPoolinglayersLabel  matlab.ui.control.Label
        Layer11Label                    matlab.ui.control.Label
        filterSizeOddSpinner_4Label     matlab.ui.control.Label
        filterSizeOddSpinner_4          matlab.ui.control.Spinner
        StrideEditField_4Label          matlab.ui.control.Label
        StrideEditField_4               matlab.ui.control.NumericEditField
        PaddingSizeEditField_4Label     matlab.ui.control.Label
        PaddingSizeEditField_4          matlab.ui.control.NumericEditField
        PoolingSizeOddEditField_4Label  matlab.ui.control.Label
        PoolingSizeOddEditField_4       matlab.ui.control.NumericEditField
        numFiltersEditField_4Label      matlab.ui.control.Label
        numFiltersEditField_4           matlab.ui.control.NumericEditField
        NumberofconvolutionlayersEditFieldLabel  matlab.ui.control.Label
        NumberofconvolutionlayersEditField  matlab.ui.control.NumericEditField
        NumberoftotallayersEditFieldLabel  matlab.ui.control.Label
        NumberoftotallayersEditField    matlab.ui.control.NumericEditField
        Layer14Label                    matlab.ui.control.Label
        filterSizeOddSpinner_5Label     matlab.ui.control.Label
        filterSizeOddSpinner_5          matlab.ui.control.Spinner
        StrideEditField_5Label          matlab.ui.control.Label
        StrideEditField_5               matlab.ui.control.NumericEditField
        PaddingSizeEditField_5Label     matlab.ui.control.Label
        PaddingSizeEditField_5          matlab.ui.control.NumericEditField
        PoolingSizeOddEditField_5Label  matlab.ui.control.Label
        PoolingSizeOddEditField_5       matlab.ui.control.NumericEditField
        numFiltersEditField_5Label      matlab.ui.control.Label
        numFiltersEditField_5           matlab.ui.control.NumericEditField
        Layer17Label                    matlab.ui.control.Label
        filterSizeOddSpinner_6Label     matlab.ui.control.Label
        filterSizeOddSpinner_6          matlab.ui.control.Spinner
        StrideEditField_6Label          matlab.ui.control.Label
        StrideEditField_6               matlab.ui.control.NumericEditField
        PaddingSizeEditField_6Label     matlab.ui.control.Label
        PaddingSizeEditField_6          matlab.ui.control.NumericEditField
        PoolingSizeOddEditField_6Label  matlab.ui.control.Label
        PoolingSizeOddEditField_6       matlab.ui.control.NumericEditField
        numFiltersEditField_6Label      matlab.ui.control.Label
        numFiltersEditField_6           matlab.ui.control.NumericEditField
        ActivityofconvolutionlayerButton  matlab.ui.control.Button
        layerDropDownLabel              matlab.ui.control.Label
        layerDropDown                   matlab.ui.control.DropDown
        WidthEditFieldLabel             matlab.ui.control.Label
        WidthEditField                  matlab.ui.control.NumericEditField
        HeightEditFieldLabel            matlab.ui.control.Label
        HeightEditField                 matlab.ui.control.NumericEditField
        chanelsLabel                    matlab.ui.control.Label
        chanelsEditField                matlab.ui.control.NumericEditField
        ViewoffeaturemapButton          matlab.ui.control.Button
        ConvolutionLayer35FullyConnectedLayerLabel  matlab.ui.control.Label
        Stride11Label                   matlab.ui.control.Label
        Stride11Label_2                 matlab.ui.control.Label
        Stride11Label_3                 matlab.ui.control.Label
        PaddingSize0000Label            matlab.ui.control.Label
        PaddingSize0000Label_2          matlab.ui.control.Label
        PaddingSize0000Label_3          matlab.ui.control.Label
        Stride11Label_4                 matlab.ui.control.Label
        Stride11Label_5                 matlab.ui.control.Label
        Stride11Label_6                 matlab.ui.control.Label
        PaddingSize0000Label_4          matlab.ui.control.Label
        PaddingSize0000Label_5          matlab.ui.control.Label
        PaddingSize0000Label_6          matlab.ui.control.Label
    end


     properties (Access = public)
%         numFilters 
%         filterSize
%         Pooling
%         Stride
%         Padding % Description
        %
        numFilters2
        filterSize2
        Pooling2
        Padding2
        Stride2
        %
        numFilters5
        filterSize5
        Pooling5
        Padding5
        Stride5
        %
        numFilters8
        filterSize8
        Pooling8
        Padding8
        Stride8
        %
        numFilters11
        filterSize11
        Pooling11
        Padding11
        Stride11
        %        
        numFilters14
        filterSize14
        Pooling14
        Padding14
        Stride14
        %
        numFilters17
        filterSize17
        Pooling17
        Padding17
        Stride17
        %d
        filter_set
        num_convolution_layers
        num_layers
        %
        sssNet %2017/12/12
        %Convolutionlayer_NUM
     end

    methods (Access = public)
        %フィルタサイズなどのパラメータを表示
        function results = func1(app)
            app.numFiltersEditField.Value=app.numFilters2;
            app.filterSizeOddSpinner.Value=app.filterSize2(1);
            app.PoolingSizeOddEditField.Value=app.Pooling2;
            app.StrideEditField.Value=app.Padding2;
            app.PaddingSizeEditField.Value=app.Stride2;
            %
            app.numFiltersEditField_2.Value=app.numFilters5;
            app.filterSizeOddSpinner_2.Value=app.filterSize5(1);
            app.PoolingSizeOddEditField_2.Value=app.Pooling5;
            app.StrideEditField_2.Value=app.Padding5;
            app.PaddingSizeEditField_2.Value=app.Stride5;
            %
            app.numFiltersEditField_3.Value=app.numFilters8;
            app.filterSizeOddSpinner_3.Value=app.filterSize8(1);
            app.PoolingSizeOddEditField_3.Value=app.Pooling8;
            app.StrideEditField_3.Value=app.Padding8;
            app.PaddingSizeEditField_3.Value=app.Stride8;   
            %
            app.numFiltersEditField_4.Value=app.numFilters11;
            app.filterSizeOddSpinner_4.Value=app.filterSize11(1);
            app.PoolingSizeOddEditField_4.Value=app.Pooling11;
            app.StrideEditField_4.Value=app.Padding11;
            app.PaddingSizeEditField_4.Value=app.Stride11;               
            %
            app.numFiltersEditField_5.Value=app.numFilters14;
            app.filterSizeOddSpinner_5.Value=app.filterSize14(1);
            app.PoolingSizeOddEditField_5.Value=app.Pooling14;
            app.StrideEditField_5.Value=app.Padding14;
            app.PaddingSizeEditField_5.Value=app.Stride14;               
            %
            app.numFiltersEditField_6.Value=app.numFilters17;
            app.filterSizeOddSpinner_6.Value=app.filterSize17(1);
            app.PoolingSizeOddEditField_6.Value=app.Pooling17;
            app.StrideEditField_6.Value=app.Padding17;
            app.PaddingSizeEditField_6.Value=app.Stride17;        
            %
            app.NumberofconvolutionlayersEditField.Value = app.num_convolution_layers;
            %
            func5(app);
        end
        %フィルタエディットAPPを消去
        function results = func2(app)
            delete(app);
        end
        
    end

    methods (Access = private)
        function results = layer4_active(app)
            app.numFiltersEditField_4.Enable = 'on';
            app.filterSizeOddSpinner_4.Enable = 'on';
            app.PoolingSizeOddEditField_4.Enable ='on';
            app.StrideEditField_4.Enable = 'on';
            app.PaddingSizeEditField_4.Enable = 'on';
        end
        
        function results = layer4_inactive(app)
            app.numFiltersEditField_4.Enable = 'off';
            app.filterSizeOddSpinner_4.Enable = 'off';
            app.PoolingSizeOddEditField_4.Enable ='off';
            app.StrideEditField_4.Enable = 'off';
            app.PaddingSizeEditField_4.Enable = 'off';
        end
        
         function results = layer5_active(app)
            app.numFiltersEditField_5.Enable = 'on';
            app.filterSizeOddSpinner_5.Enable = 'on';
            app.PoolingSizeOddEditField_5.Enable ='on';
            app.StrideEditField_5.Enable = 'on';
            app.PaddingSizeEditField_5.Enable = 'on';
        end
        
        function results = layer5_inactive(app)
            app.numFiltersEditField_5.Enable = 'off';
            app.filterSizeOddSpinner_5.Enable = 'off';
            app.PoolingSizeOddEditField_5.Enable ='off';
            app.StrideEditField_5.Enable = 'off';
            app.PaddingSizeEditField_5.Enable = 'off';
        end 
        
         function results = layer6_active(app)
            app.numFiltersEditField_6.Enable = 'on';
            app.filterSizeOddSpinner_6.Enable = 'on';
            app.PoolingSizeOddEditField_6.Enable ='on';
            app.StrideEditField_6.Enable = 'on';
            app.PaddingSizeEditField_6.Enable = 'on';
        end
        
        function results = layer6_inactive(app)
            app.numFiltersEditField_6.Enable = 'off';
            app.filterSizeOddSpinner_6.Enable = 'off';
            app.PoolingSizeOddEditField_6.Enable ='off';
            app.StrideEditField_6.Enable = 'off';
            app.PaddingSizeEditField_6.Enable = 'off';
         end         
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
        function results = func5(app)
        %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%    
           value = app.NumberofconvolutionlayersEditField.Value;
            app.NumberoftotallayersEditField.Value = 15+(value-3)*3;
            if value ==3
               layer4_inactive(app) ;
               layer5_inactive(app) ;
               layer6_inactive(app) ;               
            elseif value ==4
               layer4_active(app) ;
               layer5_inactive(app) ;
               layer6_inactive(app) ;
            elseif value ==5
               layer4_active(app) ;
               layer5_active(app) ;
               layer6_inactive(app) ;
            elseif value ==6
               layer4_active(app) ;
               layer5_active(app) ;
               layer6_active(app) ;
            else
                ;
            end  
            switch app.NumberofconvolutionlayersEditField.Value
                case 3
                    app.layerDropDown.Items = {'2' '5' '8' '11' '13'};% layers 11 and 13 are fully connected layers.
                case 4
                    app.layerDropDown.Items = {'2' '5' '8' '11' '14' '16'};% layers 14 and 16 are fully connected layers.
                case 5
                    app.layerDropDown.Items ={'2' '5' '8' '11' '14' '17' '19'};% layers 17 and 19 are fully connected layers.
                case 6
                    app.layerDropDown.Items ={'2' '5' '8' '11' '14' '17' '20' '22'};% layers 20 and 22 are fully connected layers.
                otherwise
                    app.layerDropDown.Items =  {'2' '5' '8' '11' '13'};
            end
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
%             app.filterSizeOddSpinner.Value=app.filterSize2(1);
%             app.PoolingSizeOddEditField.Value=app.Pooling2;
%             app.StrideEditField.Value=app.Padding2;
%             app.PaddingSizeEditField.Value=app.Stride2;
%             %
%             app.filterSizeOddSpinner_2.Value=app.filterSize5(1);
%             app.PoolingSizeOddEditField_2.Value=app.Pooling5;
%             app.StrideEditField_2.Value=app.Padding5;
%             app.PaddingSizeEditField_2.Value=app.Stride5;
%             %
%             app.filterSizeOddSpinner_3.Value=app.filterSize8(1);
%             app.PoolingSizeOddEditField_3.Value=app.Pooling8;
%             app.StrideEditField_3.Value=app.Padding8;
%             app.PaddingSizeEditField_3.Value=app.Stride8; 
            app.filter_set = 0 ;
            app.NumberofconvolutionlayersEditField.Value = 3;
            app.NumberoftotallayersEditField.Value = 15 + (app.NumberofconvolutionlayersEditField.Value - 3) * 3;
            %
                  switch app.NumberofconvolutionlayersEditField.Value
                        case 3
                            app.numFiltersEditField_4.Enable = 'off';
                            app.filterSizeOddSpinner_4.Enable = 'off';
                            app.PoolingSizeOddEditField_4.Enable ='off';
                            app.StrideEditField_4.Enable = 'off';
                            app.PaddingSizeEditField_4.Enable = 'off';
                            app.numFiltersEditField_5.Enable = 'off';  
                            app.filterSizeOddSpinner_5.Enable = 'off';
                            app.PoolingSizeOddEditField_5.Enable ='off';
                            app.StrideEditField_5.Enable = 'off';
                            app.PaddingSizeEditField_5.Enable = 'off';
                            app.numFiltersEditField_6.Enable = 'off';
                            app.filterSizeOddSpinner_6.Enable = 'off';
                            app.PoolingSizeOddEditField_6.Enable ='off';
                            app.StrideEditField_6.Enable = 'off';
                            app.PaddingSizeEditField_6.Enable = 'off';
                        case 4   
                                
                            app.numFiltersEditField_5.Enable = 'off';
                            app.filterSizeOddSpinner_5.Enable = 'off';
                            app.PoolingSizeOddEditField_5.Enable ='off';
                            app.StrideEditField_5.Enable = 'off';
                            app.PaddingSizeEditField_5.Enable = 'off';
                            app.numFiltersEditField_6.Enable = 'off';
                            app.filterSizeOddSpinner_6.Enable = 'off';
                            app.PoolingSizeOddEditField_6.Enable ='off';
                            app.StrideEditField_6.Enable = 'off';
                            app.PaddingSizeEditField_6.Enable = 'off';
                        case 5   
                            app.numFiltersEditField_6.Enable = 'off';
                            app.filterSizeOddSpinner_6.Enable = 'off';
                            app.PoolingSizeOddEditField_6.Enable ='off';
                            app.StrideEditField_6.Enable = 'off';
                            app.PaddingSizeEditField_6.Enable = 'off';
                        case 6   
                            ;
                        otherwise
                            ;                            
                  end
%                   switch app.NumberofconvolutionlayersEditField.Value
%                     case 3
%                         app.layerDropDown.Items = {'2' '5' '8' '11' '13'};% layers 11 and 13 are fully connected layers.
%                     case 4
%                         app.layerDropDown.Items = {'2' '5' '8' '11' '14' '16'};% layers 14 and 16 are fully connected layers.
%                     case 5
%                         app.layerDropDown.Items ={'2' '5' '8' '11' '14' '17' '19'};% layers 17 and 19 are fully connected layers.
%                     case 6
%                         app.layerDropDown.Items ={'2' '5' '8' '11' '14' '17' '20' '22'};% layers 20 and 22 are fully connected layers.
%                     otherwise
%                         app.layerDropDown.Items =  {'2' '5' '8' '11' '13'};
%                   end      
                    func5(app);%2017/12/12
                  %layerDropDownValueChanged(app, event);
        end

        % Value changed function: PaddingSizeEditField
        function PaddingSizeEditFieldValueChanged(app, event)
            app.Padding2 = app.PaddingSizeEditField.Value;
            %app.filterSize = app.Padding * 2 + 1 ;%Paddingに基づきフィルタサイズをセット
            app.filterSize2 = [app.Padding2*2+1 app.Padding2*2+1];%Paddingに基づきフィルタサイズをセット
            %app.filterSizeOddSpinner.Value = app.filterSize;
            app.filterSizeOddSpinner.Value = app.Padding2*2+1;

        end

        % Button pushed function: 
        % ParametersSettingfor6PreTrainingofDCNNButton
        function ParametersSettingfor6PreTrainingofDCNNButtonPushed(app, event)
        %　設定した値をメインダイアログ（DNNのメニュー）へ送るために、app.filter_set = 1 
        %app.filterSize = [app.filterSizeOddSpinner.Value app.filterSizeOddSpinner.Value];
        %app.Pooling=app.PoolingSizeOddEditField.Value;
        %app.Stride=app.StrideEditField.Value;
        %app.Padding=app.PaddingSizeEditField.Value;
        %
        app.numFilters2=app.numFiltersEditField.Value;
        app.filterSize2=[app.filterSizeOddSpinner.Value app.filterSizeOddSpinner.Value];
        app.Pooling2=app.PoolingSizeOddEditField.Value;
        app.Stride2=app.StrideEditField.Value;
        app.Padding2=app.PaddingSizeEditField.Value;
        %
        app.numFilters5=app.numFiltersEditField_2.Value;
        app.filterSize5=[app.filterSizeOddSpinner_2.Value app.filterSizeOddSpinner_2.Value];
        app.Pooling5=app.PoolingSizeOddEditField_2.Value;
        app.Stride5=app.StrideEditField_2.Value;
        app.Padding5=app.PaddingSizeEditField_2.Value;
        %
        app.numFilters8=app.numFiltersEditField_3.Value;
        app.filterSize8=[app.filterSizeOddSpinner_3.Value app.filterSizeOddSpinner_3.Value];
        app.Pooling8=app.PoolingSizeOddEditField_3.Value;
        app.Stride8=app.StrideEditField_3.Value;
        app.Padding8=app.PaddingSizeEditField_3.Value;
        %
        app.numFilters11=app.numFiltersEditField_4.Value;
        app.filterSize11=[app.filterSizeOddSpinner_4.Value app.filterSizeOddSpinner_4.Value];
        app.Pooling11=app.PoolingSizeOddEditField_4.Value;
        app.Stride11=app.StrideEditField_4.Value;
        app.Padding11=app.PaddingSizeEditField_4.Value;        %
        %
        app.numFilters14=app.numFiltersEditField_5.Value;
        app.filterSize14=[app.filterSizeOddSpinner_5.Value app.filterSizeOddSpinner_5.Value];
        app.Pooling14=app.PoolingSizeOddEditField_5.Value;
        app.Stride14=app.StrideEditField_5.Value;
        app.Padding14=app.PaddingSizeEditField_5.Value;
        %
        app.numFilters17=app.numFiltersEditField_6.Value;
        app.filterSize17=[app.filterSizeOddSpinner_6.Value app.filterSizeOddSpinner_6.Value];
        app.Pooling17=app.PoolingSizeOddEditField_6.Value;
        app.Stride17=app.StrideEditField_6.Value;
        app.Padding17=app.PaddingSizeEditField_6.Value;
        %
        %app.d=DeepLearningEx_Max12_20171028G_;
        %app.d.func3;
        %DeepLearningEx_Max12_20171028G_.func3;
         app.filter_set = 1 ;%フィルタが設定された！
         app.num_convolution_layers = app.NumberofconvolutionlayersEditField.Value ;%畳み層の数（初期値は3）
        end

        % Close request function: 
        % Filteredit2018714designedbyFNagataUIFigure
        function Filteredit2018714designedbyFNagataUIFigureCloseRequest(app, event)
            delete(app)
            
        end

        % Value changed function: filterSizeOddSpinner
        function filterSizeOddSpinnerValueChanged(app, event)
            %value = app.filterSizeOddSpinner.Value;
            app.filterSize2 = [app.filterSizeOddSpinner.Value app.filterSizeOddSpinner.Value];            
            app.Padding2 = (app.filterSizeOddSpinner.Value-1)/2; %フィルタサイズに基づきPaddingをセット
            app.PaddingSizeEditField.Value= app.Padding2;            
        end

        % Value changed function: filterSizeOddSpinner_2
        function filterSizeOddSpinner_2ValueChanged(app, event)
            %value = app.filterSizeOddSpinner_2.Value;
            app.filterSize5 = [app.filterSizeOddSpinner_2.Value app.filterSizeOddSpinner_2.Value];            
            app.Padding5 = (app.filterSizeOddSpinner_2.Value-1)/2; %フィルタサイズに基づきPaddingをセット
            app.PaddingSizeEditField_2.Value= app.Padding5;             
        end

        % Value changed function: filterSizeOddSpinner_3
        function filterSizeOddSpinner_3ValueChanged(app, event)
            %value = app.filterSizeOddSpinner_3.Value;            
            app.filterSize8 = [app.filterSizeOddSpinner_3.Value app.filterSizeOddSpinner_3.Value];            
            app.Padding8 = (app.filterSizeOddSpinner_3.Value-1)/2; %フィルタサイズに基づきPaddingをセット
            app.PaddingSizeEditField_3.Value= app.Padding8;    
            
        end

        % Value changed function: PaddingSizeEditField_2
        function PaddingSizeEditField_2ValueChanged(app, event)
            %value = app.PaddingSizeEditField_2.Value;
            app.Padding5 = app.PaddingSizeEditField_2.Value;
            %app.filterSize = app.Padding * 2 + 1 ;%Paddingに基づきフィルタサイズをセット
            app.filterSize5 = [app.Padding5*2+1 app.Padding5*2+1];%Paddingに基づきフィルタサイズをセット
            %app.filterSizeOddSpinner.Value = app.filterSize;
            app.filterSizeOddSpinner_2.Value = app.Padding5*2+1;            
        end

        % Value changed function: PaddingSizeEditField_3
        function PaddingSizeEditField_3ValueChanged(app, event)
            %value = app.PaddingSizeEditField_2.Value;
            app.Padding8 = app.PaddingSizeEditField_3.Value;
            %app.filterSize = app.Padding * 2 + 1 ;%Paddingに基づきフィルタサイズをセット
            app.filterSize8 = [app.Padding8*2+1 app.Padding8*2+1];%Paddingに基づきフィルタサイズをセット
            %app.filterSizeOddSpinner.Value = app.filterSize;
            app.filterSizeOddSpinner_3.Value = app.Padding8*2+1;         
            
        end

        % Value changed function: NumberofconvolutionlayersEditField
        function NumberofconvolutionlayersEditFieldValueChanged(app, event)
            app.func5;
            %

        end

        % Value changed function: filterSizeOddSpinner_4
        function filterSizeOddSpinner_4ValueChanged(app, event)
            %value = app.filterSizeOddSpinner_4.Value;
            app.filterSize11 = [app.filterSizeOddSpinner_4.Value app.filterSizeOddSpinner_4.Value];            
            app.Padding11 = (app.filterSizeOddSpinner_4.Value-1)/2; %フィルタサイズに基づきPaddingをセット
            app.PaddingSizeEditField_4.Value= app.Padding11;              
        end

        % Value changed function: filterSizeOddSpinner_5
        function filterSizeOddSpinner_5ValueChanged(app, event)
            %value = app.filterSizeOddSpinner_5.Value;
            app.filterSize14 = [app.filterSizeOddSpinner_5.Value app.filterSizeOddSpinner_5.Value];            
            app.Padding14 = (app.filterSizeOddSpinner_5.Value-1)/2; %フィルタサイズに基づきPaddingをセット
            app.PaddingSizeEditField_5.Value= app.Padding14;              
        end

        % Value changed function: filterSizeOddSpinner_6
        function filterSizeOddSpinner_6ValueChanged(app, event)
            %value = app.filterSizeOddSpinner_6.Value;
            app.filterSize17 = [app.filterSizeOddSpinner_6.Value app.filterSizeOddSpinner_6.Value];            
            app.Padding17 = (app.filterSizeOddSpinner_6.Value-1)/2; %フィルタサイズに基づきPaddingをセット
            app.PaddingSizeEditField_6.Value= app.Padding17;             
        end

        % Button pushed function: ActivityofconvolutionlayerButton
        function ActivityofconvolutionlayerButtonPushed(app, event)
            [filename, pathname] = uigetfile('*.jpg', 'Select a image file (.jpg)');
            s = strcat(pathname,filename);
            IMAGE=imread(s);
            imgSize = size(IMAGE);
            imgSize = imgSize(1:2);
%             if app.layerDropDown.Value =='2'
%                 c='conv_1';
%             elseif app.layerDropDown.Value =='5'       
%                 c='conv_2';   
%             elseif app.layerDropDown.Value =='8'
%                 c='conv_3';
%             elseif app.layerDropDown.Value =='11'
%                 c='conv_4';
%             elseif app.layerDropDown.Value =='14'
%                 c='conv_5';
%             elseif app.layerDropDown.Value =='17'
%                 c='conv_6';
%             else
%                 c='conv_1';
%                 app.layerDropDown.Value ='2';
%             end
%            c=app.sssNet.Layers(str2double(app.layerDropDown.Value));
             c=str2double(app.layerDropDown.Value);
            try
                act1 = activations(app.sssNet,IMAGE,c,'OutputAs','channels');
                sz = size(act1);
                act1 = reshape(act1,[sz(1) sz(2) 1 sz(3)]);
                app.WidthEditField.Value = sz(2);
                app.HeightEditField.Value = sz(1);
                app.chanelsEditField.Value = sz(3);
                figure(1);
                montage(mat2gray(act1),'Size',[8 8]);
                title(['第',num2str(app.layerDropDown.Value),'層の畳み込み層は',num2str(app.chanelsEditField.Value),'枚の"チャネル" と呼ばれる2次元配列で構成されており、その活性化状況です']);
    %            title(['Layer ',app.sssNet.Layers(n).Name,' Activity']); 
    %           最も強い活性化チャネルの確認
                [maxValue,maxValueIndex] = max(max(max(act1)));
                act1chMax = act1(:,:,:,maxValueIndex);
                act1chMax = mat2gray(act1chMax);
                act1chMax = imresize(act1chMax,imgSize);
                figure(2);
                imshowpair(IMAGE,act1chMax,'montage');           
                %title(['Temperature is ',num2str(c),' C'])
                title(['左側は元のイメージ、右側は最も強い活性化チャネル：',num2str(maxValueIndex)]);                
            catch
                fprintf('activations関数コール時にエラーが発生しました。\n'); 
            end

        end

        % Value changed function: layerDropDown
        function layerDropDownValueChanged(app, event)
            %app.Convolutionlayer_NUM = app.layerDropDown.Value;
%            NumChannelsSpinnerValueChanged(app, event);    
%            if value == 3
%                ;
%            end            
        end

        % Button pushed function: ViewoffeaturemapButton
        function ViewoffeaturemapButtonPushed(app, event)
%            [filename, pathname] = uigetfile('*.jpg', 'Select a image file (.jpg)');
%            s = strcat(pathname,filename);
%            IMAGE=imread(s);
%            imgSize = size(IMAGE);
%            imgSize = imgSize(1:2);
%             switch app.NumberofconvolutionlayersEditField.Value
%                 case 3
%                     app.layerDropDown.Items = {'2' '5' '8' '11' '13'};% layers 11 and 13 are fully connected layers.
%                 case 4
%                     app.layerDropDown.Items = {'2' '5' '8' '11' '14' '16'};% layers 14 and 16 are fully connected layers.
%                 case 5
%                     app.layerDropDown.Items ={'2' '5' '8' '11' '14' '17' '19'};% layers 17 and 19 are fully connected layers.
%                 case 6
%                     app.layerDropDown.Items ={'2' '5' '8' '11' '14' '17' '20' '22'};% layers 20 and 22 are fully connected layers.
%                 otherwise
%                     app.layerDropDown.Items =  {'2' '5' '8' '11' '13'};
%             end
%             if app.layerDropDown.Value =='2'
%                 c=2;
%             elseif app.layerDropDown.Value =='5'       
%                 c=5;                    
%             elseif app.layerDropDown.Value =='8'
%                 c=8;                    
%             elseif app.layerDropDown.Value =='11'
%                 c=11; 
%             elseif app.layerDropDown.Value =='13'
%                 c=13;                    
%             elseif app.layerDropDown.Value =='14'
%                 c=14;                    
%             elseif app.layerDropDown.Value =='16'
%                 c=16;                    
%             elseif app.layerDropDown.Value =='17'
%                 c=17;
%             elseif app.layerDropDown.Value =='19'
%                 c=19;   
%             elseif app.layerDropDown.Value =='20'
%                 c=20;   
%             elseif app.layerDropDown.Value =='22'
%                 c=22;                
%             else
%                 c=2;                         
%             end       
 %
            c=str2double(app.layerDropDown.Value);
 %          
            try
                channels = 1:app.sssNet.Layers(c).NumFilters;%in case of convolution layer
                fprintf('畳み込み層 (convolution layer)です。\n'); 
            catch
                channels = 1:app.sssNet.Layers(c).OutputSize;%in case of fully connected layer
                 fprintf('全結合層です (fully connected layer)。\n'); 
            end
%AlexNet を使用した Deep Dream イメージ
%             I = deepDreamImage(net,layer,channels, ...
%             'Verbose',false, ...
%             'NumIterations',iterations);
%             I = deepDreamImage(net,layer,channels, ...
%             'Verbose',false, ...
%             'NumIterations',iterations, ...
%             'PyramidLevels',levels);
%             I = deepDreamImage(app.sssNet,c,channels, ...
%             'PyramidLevels',1);
            %150x200 の画像では、全結合層の表示がエラーとなってしまう。2018/2/19
            try
                I = deepDreamImage(app.sssNet,c,channels, ...
                'Verbose',false, ...
                'NumIterations',10, ...
                'PyramidLevels',3);
                figure;
                montage(I);
                title(['Layer ',app.sssNet.Layers(c).Name,' Features']); 
            catch
                fprintf('deepDreamImage関数コール時にエラーが発生しました。\n'); 
            end

        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Filteredit2018714designedbyFNagataUIFigure = uifigure;
            app.Filteredit2018714designedbyFNagataUIFigure.Name = 'Filter edit (2018-7-14)  designed by F. Nagata';
            app.Filteredit2018714designedbyFNagataUIFigure.CloseRequestFcn = createCallbackFcn(app, @Filteredit2018714designedbyFNagataUIFigureCloseRequest, true);
            app.filterSizeOddSpinnerLabel = uilabel(app.Filteredit2018714designedbyFNagataUIFigure);
            app.filterSizeOddSpinnerLabel.Text = 'filterSize (Odd)';
            app.filterSizeOddSpinner = uispinner(app.Filteredit2018714designedbyFNagataUIFigure);
            app.filterSizeOddSpinner.Limits = [1 100];
            app.filterSizeOddSpinner.ValueChangedFcn = createCallbackFcn(app, @filterSizeOddSpinnerValueChanged, true);
            app.filterSizeOddSpinner.Value = 5;
            app.StrideEditFieldLabel = uilabel(app.Filteredit2018714designedbyFNagataUIFigure);
            app.StrideEditFieldLabel.Text = 'Stride';
            app.StrideEditField = uieditfield(app.Filteredit2018714designedbyFNagataUIFigure, 'numeric');
            app.StrideEditField.Limits = [1 10];
            app.StrideEditField.Value = 2;
            app.PaddingSizeEditFieldLabel = uilabel(app.Filteredit2018714designedbyFNagataUIFigure);
            app.PaddingSizeEditFieldLabel.Text = 'PaddingSize';
            app.PaddingSizeEditField = uieditfield(app.Filteredit2018714designedbyFNagataUIFigure, 'numeric');
            app.PaddingSizeEditField.Limits = [1 100];
            app.PaddingSizeEditField.ValueChangedFcn = createCallbackFcn(app, @PaddingSizeEditFieldValueChanged, true);
            app.PaddingSizeEditField.Value = 2;
            app.PoolingSizeOddEditFieldLabel = uilabel(app.Filteredit2018714designedbyFNagataUIFigure);
            app.PoolingSizeOddEditFieldLabel.Text = 'PoolingSize (Odd)';
            app.PoolingSizeOddEditField = uieditfield(app.Filteredit2018714designedbyFNagataUIFigure, 'numeric');
            app.PoolingSizeOddEditField.Limits = [1 10];
            app.PoolingSizeOddEditField.Value = 3;
            app.ParametersSettingfor6PreTrainingofDCNNButton = uibutton(app.Filteredit2018714designedbyFNagataUIFigure, 'push');
            app.ParametersSettingfor6PreTrainingofDCNNButton.ButtonPushedFcn = createCallbackFcn(app, @ParametersSettingfor6PreTrainingofDCNNButtonPushed, true);
            app.ParametersSettingfor6PreTrainingofDCNNButton.Text = 'Parameters Setting for [6. Pre-Training of DCNN]';
            app.filterSizeOddSpinner_2Label = uilabel(app.Filteredit2018714designedbyFNagataUIFigure);
            app.filterSizeOddSpinner_2Label.Text = 'filterSize (Odd)';
            app.filterSizeOddSpinner_2 = uispinner(app.Filteredit2018714designedbyFNagataUIFigure);
            app.filterSizeOddSpinner_2.Limits = [1 100];
            app.filterSizeOddSpinner_2.ValueChangedFcn = createCallbackFcn(app, @filterSizeOddSpinner_2ValueChanged, true);
            app.filterSizeOddSpinner_2.Value = 5;
            app.StrideEditField_2Label = uilabel(app.Filteredit2018714designedbyFNagataUIFigure);
            app.StrideEditField_2Label.Text = 'Stride';
            app.StrideEditField_2 = uieditfield(app.Filteredit2018714designedbyFNagataUIFigure, 'numeric');
            app.StrideEditField_2.Limits = [1 10];
            app.StrideEditField_2.Value = 2;
            app.PaddingSizeEditField_2Label = uilabel(app.Filteredit2018714designedbyFNagataUIFigure);
            app.PaddingSizeEditField_2Label.Text = 'PaddingSize';
            app.PaddingSizeEditField_2 = uieditfield(app.Filteredit2018714designedbyFNagataUIFigure, 'numeric');
            app.PaddingSizeEditField_2.Limits = [1 100];
            app.PaddingSizeEditField_2.ValueChangedFcn = createCallbackFcn(app, @PaddingSizeEditField_2ValueChanged, true);
            app.PaddingSizeEditField_2.Value = 2;
            app.PoolingSizeOddEditField_2Label = uilabel(app.Filteredit2018714designedbyFNagataUIFigure);
            app.PoolingSizeOddEditField_2Label.Text = 'PoolingSize (Odd)';
            app.PoolingSizeOddEditField_2 = uieditfield(app.Filteredit2018714designedbyFNagataUIFigure, 'numeric');
            app.PoolingSizeOddEditField_2.Limits = [1 10];
            app.PoolingSizeOddEditField_2.Value = 3;
            app.filterSizeOddSpinner_3Label = uilabel(app.Filteredit2018714designedbyFNagataUIFigure);
            app.filterSizeOddSpinner_3Label.Text = 'filterSize (Odd)';
            app.filterSizeOddSpinner_3 = uispinner(app.Filteredit2018714designedbyFNagataUIFigure);
            app.filterSizeOddSpinner_3.Limits = [1 100];
            app.filterSizeOddSpinner_3.ValueChangedFcn = createCallbackFcn(app, @filterSizeOddSpinner_3ValueChanged, true);
            app.filterSizeOddSpinner_3.Value = 5;
            app.StrideEditField_3Label = uilabel(app.Filteredit2018714designedbyFNagataUIFigure);
            app.StrideEditField_3Label.Text = 'Stride';
            app.StrideEditField_3 = uieditfield(app.Filteredit2018714designedbyFNagataUIFigure, 'numeric');
            app.StrideEditField_3.Limits = [1 10];
            app.StrideEditField_3.Value = 2;
            app.PaddingSizeEditField_3Label = uilabel(app.Filteredit2018714designedbyFNagataUIFigure);
            app.PaddingSizeEditField_3Label.Text = 'PaddingSize';
            app.PaddingSizeEditField_3 = uieditfield(app.Filteredit2018714designedbyFNagataUIFigure, 'numeric');
            app.PaddingSizeEditField_3.Limits = [1 100];
            app.PaddingSizeEditField_3.ValueChangedFcn = createCallbackFcn(app, @PaddingSizeEditField_3ValueChanged, true);
            app.PaddingSizeEditField_3.Value = 2;
            app.PoolingSizeOddEditField_3Label = uilabel(app.Filteredit2018714designedbyFNagataUIFigure);
            app.PoolingSizeOddEditField_3Label.Text = 'PoolingSize (Odd)';
            app.PoolingSizeOddEditField_3 = uieditfield(app.Filteredit2018714designedbyFNagataUIFigure, 'numeric');
            app.PoolingSizeOddEditField_3.Limits = [1 10];
            app.PoolingSizeOddEditField_3.Value = 3;
            app.Layer2Label = uilabel(app.Filteredit2018714designedbyFNagataUIFigure);
            app.Layer2Label.Text = 'Layer 2';
            app.Layer5Label = uilabel(app.Filteredit2018714designedbyFNagataUIFigure);
            app.Layer5Label.Text = 'Layer 5';
            app.Layer8Label = uilabel(app.Filteredit2018714designedbyFNagataUIFigure);
            app.Layer8Label.Text = 'Layer 8';
            app.Thisisnotappliedto7AdditionalTrainingofDNNLabel = uilabel(app.Filteredit2018714designedbyFNagataUIFigure);
            app.Thisisnotappliedto7AdditionalTrainingofDNNLabel.Text = 'This is not applied to [ 7. Additional Training of DNN ]';
            app.numFiltersEditFieldLabel = uilabel(app.Filteredit2018714designedbyFNagataUIFigure);
            app.numFiltersEditFieldLabel.Text = 'numFilters';
            app.numFiltersEditField = uieditfield(app.Filteredit2018714designedbyFNagataUIFigure, 'numeric');
            app.numFiltersEditField.Limits = [16 320];
            app.numFiltersEditField.Value = 32;
            app.numFiltersEditField_2Label = uilabel(app.Filteredit2018714designedbyFNagataUIFigure);
            app.numFiltersEditField_2Label.Text = 'numFilters';
            app.numFiltersEditField_2 = uieditfield(app.Filteredit2018714designedbyFNagataUIFigure, 'numeric');
            app.numFiltersEditField_2.Limits = [16 320];
            app.numFiltersEditField_2.Value = 32;
            app.numFiltersEditField_3Label = uilabel(app.Filteredit2018714designedbyFNagataUIFigure);
            app.numFiltersEditField_3Label.Text = 'numFilters';
            app.numFiltersEditField_3 = uieditfield(app.Filteredit2018714designedbyFNagataUIFigure, 'numeric');
            app.numFiltersEditField_3.Limits = [16 Inf];
            app.numFiltersEditField_3.Value = 64;
            app.TrainingparametersofconvolutionlayersandPoolinglayersLabel = uilabel(app.Filteredit2018714designedbyFNagataUIFigure);
            app.TrainingparametersofconvolutionlayersandPoolinglayersLabel.Text = 'Training parameters of convolution layers and Pooling layers';
            app.Layer11Label = uilabel(app.Filteredit2018714designedbyFNagataUIFigure);
            app.Layer11Label.Text = 'Layer 11';
            app.filterSizeOddSpinner_4Label = uilabel(app.Filteredit2018714designedbyFNagataUIFigure);
            app.filterSizeOddSpinner_4Label.Text = 'filterSize (Odd)';
            app.filterSizeOddSpinner_4 = uispinner(app.Filteredit2018714designedbyFNagataUIFigure);
            app.filterSizeOddSpinner_4.Limits = [1 100];
            app.filterSizeOddSpinner_4.ValueChangedFcn = createCallbackFcn(app, @filterSizeOddSpinner_4ValueChanged, true);
            app.filterSizeOddSpinner_4.Value = 5;
            app.StrideEditField_4Label = uilabel(app.Filteredit2018714designedbyFNagataUIFigure);
            app.StrideEditField_4Label.Text = 'Stride';
            app.StrideEditField_4 = uieditfield(app.Filteredit2018714designedbyFNagataUIFigure, 'numeric');
            app.StrideEditField_4.Limits = [1 10];
            app.StrideEditField_4.Value = 2;
            app.PaddingSizeEditField_4Label = uilabel(app.Filteredit2018714designedbyFNagataUIFigure);
            app.PaddingSizeEditField_4Label.Text = 'PaddingSize';
            app.PaddingSizeEditField_4 = uieditfield(app.Filteredit2018714designedbyFNagataUIFigure, 'numeric');
            app.PaddingSizeEditField_4.Limits = [1 100];
            app.PaddingSizeEditField_4.Value = 2;
            app.PoolingSizeOddEditField_4Label = uilabel(app.Filteredit2018714designedbyFNagataUIFigure);
            app.PoolingSizeOddEditField_4Label.Text = 'PoolingSize (Odd)';
            app.PoolingSizeOddEditField_4 = uieditfield(app.Filteredit2018714designedbyFNagataUIFigure, 'numeric');
            app.PoolingSizeOddEditField_4.Limits = [1 10];
            app.PoolingSizeOddEditField_4.Value = 3;
            app.numFiltersEditField_4Label = uilabel(app.Filteredit2018714designedbyFNagataUIFigure);
            app.numFiltersEditField_4Label.Text = 'numFilters';
            app.numFiltersEditField_4 = uieditfield(app.Filteredit2018714designedbyFNagataUIFigure, 'numeric');
            app.numFiltersEditField_4.Limits = [16 Inf];
            app.numFiltersEditField_4.Value = 64;
            app.NumberofconvolutionlayersEditFieldLabel = uilabel(app.Filteredit2018714designedbyFNagataUIFigure);
            app.NumberofconvolutionlayersEditFieldLabel.Text = 'Number of convolution layers';
            app.NumberofconvolutionlayersEditField = uieditfield(app.Filteredit2018714designedbyFNagataUIFigure, 'numeric');
            app.NumberofconvolutionlayersEditField.Limits = [3 6];
            app.NumberofconvolutionlayersEditField.ValueDisplayFormat = '%.0f';
            app.NumberofconvolutionlayersEditField.ValueChangedFcn = createCallbackFcn(app, @NumberofconvolutionlayersEditFieldValueChanged, true);
            app.NumberofconvolutionlayersEditField.Value = 3;
            app.NumberoftotallayersEditFieldLabel = uilabel(app.Filteredit2018714designedbyFNagataUIFigure);
            app.NumberoftotallayersEditFieldLabel.Text = 'Number of total layers';
            app.NumberoftotallayersEditField = uieditfield(app.Filteredit2018714designedbyFNagataUIFigure, 'numeric');
            app.NumberoftotallayersEditField.Limits = [3 30];
            app.NumberoftotallayersEditField.ValueDisplayFormat = '%.0f';
            app.NumberoftotallayersEditField.Editable = 'off';
            app.NumberoftotallayersEditField.Value = 3;
            app.Layer14Label = uilabel(app.Filteredit2018714designedbyFNagataUIFigure);
            app.Layer14Label.Text = 'Layer 14';
            app.filterSizeOddSpinner_5Label = uilabel(app.Filteredit2018714designedbyFNagataUIFigure);
            app.filterSizeOddSpinner_5Label.Text = 'filterSize (Odd)';
            app.filterSizeOddSpinner_5 = uispinner(app.Filteredit2018714designedbyFNagataUIFigure);
            app.filterSizeOddSpinner_5.Limits = [1 100];
            app.filterSizeOddSpinner_5.ValueChangedFcn = createCallbackFcn(app, @filterSizeOddSpinner_5ValueChanged, true);
            app.filterSizeOddSpinner_5.Value = 5;
            app.StrideEditField_5Label = uilabel(app.Filteredit2018714designedbyFNagataUIFigure);
            app.StrideEditField_5Label.Text = 'Stride';
            app.StrideEditField_5 = uieditfield(app.Filteredit2018714designedbyFNagataUIFigure, 'numeric');
            app.StrideEditField_5.Limits = [1 10];
            app.StrideEditField_5.Value = 2;
            app.PaddingSizeEditField_5Label = uilabel(app.Filteredit2018714designedbyFNagataUIFigure);
            app.PaddingSizeEditField_5Label.Text = 'PaddingSize';
            app.PaddingSizeEditField_5 = uieditfield(app.Filteredit2018714designedbyFNagataUIFigure, 'numeric');
            app.PaddingSizeEditField_5.Limits = [1 100];
            app.PaddingSizeEditField_5.Value = 2;
            app.PoolingSizeOddEditField_5Label = uilabel(app.Filteredit2018714designedbyFNagataUIFigure);
            app.PoolingSizeOddEditField_5Label.Text = 'PoolingSize (Odd)';
            app.PoolingSizeOddEditField_5 = uieditfield(app.Filteredit2018714designedbyFNagataUIFigure, 'numeric');
            app.PoolingSizeOddEditField_5.Limits = [1 10];
            app.PoolingSizeOddEditField_5.Value = 3;
            app.numFiltersEditField_5Label = uilabel(app.Filteredit2018714designedbyFNagataUIFigure);
            app.numFiltersEditField_5Label.Text = 'numFilters';
            app.numFiltersEditField_5 = uieditfield(app.Filteredit2018714designedbyFNagataUIFigure, 'numeric');
            app.numFiltersEditField_5.Limits = [16 Inf];
            app.numFiltersEditField_5.Value = 64;
            app.Layer17Label = uilabel(app.Filteredit2018714designedbyFNagataUIFigure);
            app.Layer17Label.Text = 'Layer 17';
            app.filterSizeOddSpinner_6Label = uilabel(app.Filteredit2018714designedbyFNagataUIFigure);
            app.filterSizeOddSpinner_6Label.Text = 'filterSize (Odd)';
            app.filterSizeOddSpinner_6 = uispinner(app.Filteredit2018714designedbyFNagataUIFigure);
            app.filterSizeOddSpinner_6.Limits = [1 100];
            app.filterSizeOddSpinner_6.ValueChangedFcn = createCallbackFcn(app, @filterSizeOddSpinner_6ValueChanged, true);
            app.filterSizeOddSpinner_6.Value = 5;
            app.StrideEditField_6Label = uilabel(app.Filteredit2018714designedbyFNagataUIFigure);
            app.StrideEditField_6Label.Text = 'Stride';
            app.StrideEditField_6 = uieditfield(app.Filteredit2018714designedbyFNagataUIFigure, 'numeric');
            app.StrideEditField_6.Limits = [1 10];
            app.StrideEditField_6.Value = 2;
            app.PaddingSizeEditField_6Label = uilabel(app.Filteredit2018714designedbyFNagataUIFigure);
            app.PaddingSizeEditField_6Label.Text = 'PaddingSize';
            app.PaddingSizeEditField_6 = uieditfield(app.Filteredit2018714designedbyFNagataUIFigure, 'numeric');
            app.PaddingSizeEditField_6.Limits = [1 100];
            app.PaddingSizeEditField_6.Value = 2;
            app.PoolingSizeOddEditField_6Label = uilabel(app.Filteredit2018714designedbyFNagataUIFigure);
            app.PoolingSizeOddEditField_6Label.Text = 'PoolingSize (Odd)';
            app.PoolingSizeOddEditField_6 = uieditfield(app.Filteredit2018714designedbyFNagataUIFigure, 'numeric');
            app.PoolingSizeOddEditField_6.Limits = [1 10];
            app.PoolingSizeOddEditField_6.Value = 3;
            app.numFiltersEditField_6Label = uilabel(app.Filteredit2018714designedbyFNagataUIFigure);
            app.numFiltersEditField_6Label.Text = 'numFilters';
            app.numFiltersEditField_6 = uieditfield(app.Filteredit2018714designedbyFNagataUIFigure, 'numeric');
            app.numFiltersEditField_6.Limits = [16 Inf];
            app.numFiltersEditField_6.Value = 64;
            app.ActivityofconvolutionlayerButton = uibutton(app.Filteredit2018714designedbyFNagataUIFigure, 'push');
            app.ActivityofconvolutionlayerButton.ButtonPushedFcn = createCallbackFcn(app, @ActivityofconvolutionlayerButtonPushed, true);
            app.ActivityofconvolutionlayerButton.Text = 'Activity of convolution layer';
            app.layerDropDownLabel = uilabel(app.Filteredit2018714designedbyFNagataUIFigure);
            app.layerDropDownLabel.Text = 'layer';
            app.layerDropDown = uidropdown(app.Filteredit2018714designedbyFNagataUIFigure);
            app.layerDropDown.Items = {'2', '5', '8', '11', '14', '17'};
            app.layerDropDown.ValueChangedFcn = createCallbackFcn(app, @layerDropDownValueChanged, true);
            app.layerDropDown.Value = '2';
            app.WidthEditFieldLabel = uilabel(app.Filteredit2018714designedbyFNagataUIFigure);
            app.WidthEditFieldLabel.Text = 'Width';
            app.WidthEditField = uieditfield(app.Filteredit2018714designedbyFNagataUIFigure, 'numeric');
            app.WidthEditField.Editable = 'off';
            app.HeightEditFieldLabel = uilabel(app.Filteredit2018714designedbyFNagataUIFigure);
            app.HeightEditFieldLabel.Text = 'Height';
            app.HeightEditField = uieditfield(app.Filteredit2018714designedbyFNagataUIFigure, 'numeric');
            app.HeightEditField.Editable = 'off';
            app.chanelsLabel = uilabel(app.Filteredit2018714designedbyFNagataUIFigure);
            app.chanelsLabel.Text = 'chanels,';
            app.chanelsEditField = uieditfield(app.Filteredit2018714designedbyFNagataUIFigure, 'numeric');
            app.chanelsEditField.Editable = 'off';
            app.ViewoffeaturemapButton = uibutton(app.Filteredit2018714designedbyFNagataUIFigure, 'push');
            app.ViewoffeaturemapButton.ButtonPushedFcn = createCallbackFcn(app, @ViewoffeaturemapButtonPushed, true);
            app.ViewoffeaturemapButton.Text = 'View of feature map';
            app.ConvolutionLayer35FullyConnectedLayerLabel = uilabel(app.Filteredit2018714designedbyFNagataUIFigure);
            app.ConvolutionLayer35FullyConnectedLayerLabel.Text = 'Fully Connected Layers are located at 3rd and 5th layer posterior after the last Convolution Layer.';
            app.Stride11Label = uilabel(app.Filteredit2018714designedbyFNagataUIFigure);
            app.Stride11Label.Text = 'Stride: [1 1]';
            app.Stride11Label_2 = uilabel(app.Filteredit2018714designedbyFNagataUIFigure);
            app.Stride11Label_2.Text = 'Stride: [1 1]';
            app.Stride11Label_3 = uilabel(app.Filteredit2018714designedbyFNagataUIFigure);
            app.Stride11Label_3.Text = 'Stride: [1 1]';
            app.PaddingSize0000Label = uilabel(app.Filteredit2018714designedbyFNagataUIFigure);
            app.PaddingSize0000Label.Text = 'PaddingSize: [0 0 0 0]';
            app.PaddingSize0000Label_2 = uilabel(app.Filteredit2018714designedbyFNagataUIFigure);
            app.PaddingSize0000Label_2.Text = 'PaddingSize: [0 0 0 0]';
            app.PaddingSize0000Label_3 = uilabel(app.Filteredit2018714designedbyFNagataUIFigure);
            app.PaddingSize0000Label_3.Text = 'PaddingSize: [0 0 0 0]';
            app.Stride11Label_4 = uilabel(app.Filteredit2018714designedbyFNagataUIFigure);
            app.Stride11Label_4.Text = 'Stride: [1 1]';
            app.Stride11Label_5 = uilabel(app.Filteredit2018714designedbyFNagataUIFigure);
            app.Stride11Label_5.Text = 'Stride: [1 1]';
            app.Stride11Label_6 = uilabel(app.Filteredit2018714designedbyFNagataUIFigure);
            app.Stride11Label_6.Text = 'Stride: [1 1]';
            app.PaddingSize0000Label_4 = uilabel(app.Filteredit2018714designedbyFNagataUIFigure);
            app.PaddingSize0000Label_4.Text = 'PaddingSize: [0 0 0 0]';
            app.PaddingSize0000Label_5 = uilabel(app.Filteredit2018714designedbyFNagataUIFigure);
            app.PaddingSize0000Label_5.Text = 'PaddingSize: [0 0 0 0]';
            app.PaddingSize0000Label_6 = uilabel(app.Filteredit2018714designedbyFNagataUIFigure);
            app.PaddingSize0000Label_6.Text = 'PaddingSize: [0 0 0 0]';
        end
    end

    methods (Access = public)

        % Construct app
        function app = filter_edit_20180714_Eng

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Filteredit2018714designedbyFNagataUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Filteredit2018714designedbyFNagataUIFigure)
        end
    end
end