classdef Image_Test_0309 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        ReadimageButton               matlab.ui.control.Button
        EditField                     matlab.ui.control.EditField
        CalcurateCOGButton            matlab.ui.control.Button
        ConnectivitiesButton          matlab.ui.container.ButtonGroup
        ConnectedButton_8             matlab.ui.control.RadioButton
        ConnectedButton_4             matlab.ui.control.RadioButton
        Threshold_Type                matlab.ui.container.ButtonGroup
        Otsu                          matlab.ui.control.RadioButton
        Adaptive                      matlab.ui.control.RadioButton
        Two_Thresh                    matlab.ui.control.RadioButton
        WidthEditFieldLabel           matlab.ui.control.Label
        WidthEditField_before         matlab.ui.control.NumericEditField
        HeightEditFieldLabel          matlab.ui.control.Label
        HeightEditField_before        matlab.ui.control.NumericEditField
        ChannelEditFieldLabel         matlab.ui.control.Label
        ChannelEditField_before       matlab.ui.control.NumericEditField
        ConvertBeforePropertyLabel    matlab.ui.control.Label
        ConvertAfterPropertyLabel     matlab.ui.control.Label
        WidthEditFieldLabel_2         matlab.ui.control.Label
        WidthEditField_after          matlab.ui.control.NumericEditField
        HeightEditFieldLabel_2        matlab.ui.control.Label
        HeightEditField_after         matlab.ui.control.NumericEditField
        ChannelEditFieldLabel_2       matlab.ui.control.Label
        ChannelEditField_after        matlab.ui.control.NumericEditField
        BinaryTypeButtonGroup         matlab.ui.container.ButtonGroup
        ExistingButton                matlab.ui.control.RadioButton
        OriginalButton                matlab.ui.control.RadioButton
        PickUpColorButtonGroup        matlab.ui.container.ButtonGroup
        RButton                       matlab.ui.control.RadioButton
        GButton                       matlab.ui.control.RadioButton
        BButton                       matlab.ui.control.RadioButton
        WButton                       matlab.ui.control.RadioButton
        BkButton                      matlab.ui.control.RadioButton
        Slider                        matlab.ui.control.Slider
        LowerThresholdEditFieldLabel  matlab.ui.control.Label
        Slider_2                      matlab.ui.control.Slider
        UpperThresholdEditFieldLabel  matlab.ui.control.Label
        LowerThresholdEditField       matlab.ui.control.NumericEditField
        UpperThresholdEditField       matlab.ui.control.NumericEditField
        BinarizedimageButton          matlab.ui.control.Button
        PriodsLabel                   matlab.ui.control.Label
        PeriodsEditField              matlab.ui.control.NumericEditField
        ColorMapButton                matlab.ui.control.Button
    end

    
    properties (Access = private)
        pathname        
        filename
        IMAGE_org
        estimated_COG
        Image_bw
        Ethernet %タイマー割込みチェック用の値
        update_timer %タイマー割込み用の値
    end
    
    methods (Access = private)
        
        function results = BinarizedImage(app)
            if app.ExistingButton.Value == 1 % 既存のプログラムで二値化
                Image_glay = rgb2gray(app.IMAGE_org); % RGB → Glay
                
                %関数 imbinarize を使用し、グレースケール イメージをバイナリ イメージに変換します。
                if app.Otsu.Value == 1 % Otusの二値化
                    app.Image_bw = imbinarize(Image_glay);
                elseif app.Adaptive.Value == 1 % 適応的二値化
                    Image_bw = imbinarize(Image_glay, "adaptive");
                    app.Image_bw = imcomplement(Image_bw);%閾値で二値化した値を反転する。1⇒0　あるいは　0⇒1　となる。
                else
                    IMAGE_w = imbinarize(Image_glay, app.LowerThresholdEditField.Value);
                    IMAGE_Bk = imbinarize(Image_glay, app.UpperThresholdEditField.Value);
                    app.Image_bw = immultiply(IMAGE_w, IMAGE_Bk);%画素要素ごとの積を計算　⇒　黒色部分の抽出
                end
            else
                %---------------------------------
                % 先生が作成された2値化プログラム
                %---------------------------------
                % for red
                IMAGE_R = app.IMAGE_org(:,:,1);% Red -> Grayscale
                if app.Otsu.Value == 1
                    IMAGE_R_bw= imbinarize(IMAGE_R,'global');%Globalな閾値で二値化する
                    IMAGE_R__ = imcomplement(IMAGE_R_bw);%その反転した値
                elseif app.Adaptive.Value == 1
                    IMAGE_R_bw= imbinarize(IMAGE_R,'adaptive');%Adaptiveな閾値で二値化する
                    IMAGE_R__ = imcomplement(IMAGE_R_bw);%その反転した値                    
                else
                    IMAGE_R_bw= imbinarize(IMAGE_R,app.UpperThresholdEditField.Value);%上側の閾値で二値化する
                    IMAGE_R__ = imbinarize(IMAGE_R,app.LowerThresholdEditField.Value);%下側の閾値で二値化する
                    IMAGE_R__ = imcomplement(IMAGE_R__);%下側の閾値で二値化した値を反転する。1⇒0　あるいは　0⇒1　となる。
                end
                % for green
                IMAGE_G = app.IMAGE_org(:,:,2);% Green -> Grayscale
                if app.Otsu.Value == 1
                    IMAGE_G_bw= imbinarize(IMAGE_G,'global');%Globalな閾値で二値化する
                    IMAGE_G__ = imcomplement(IMAGE_G_bw);%その反転した値
                elseif app.Adaptive.Value == 1
                    IMAGE_G_bw= imbinarize(IMAGE_G,'adaptive');%Adaptiveな閾値で二値化する
                    IMAGE_G__ = imcomplement(IMAGE_G_bw);%その反転した値                    
                else
                    IMAGE_G_bw= imbinarize(IMAGE_G,app.UpperThresholdEditField.Value);%上側の閾値で二値化する
                    IMAGE_G__ = imbinarize(IMAGE_G,app.LowerThresholdEditField.Value);%下側の閾値で二値化する
                    IMAGE_G__ = imcomplement(IMAGE_G__);%下側の閾値で二値化した値を反転する。1⇒0　あるいは　0⇒1　となる。                    
                end
                % for blue
                IMAGE_B = app.IMAGE_org(:,:,3);% Blue -> Grayscale
                if app.Otsu.Value == 1
                    IMAGE_B_bw= imbinarize(IMAGE_B,'global');%Globalな閾値で二値化する
                    IMAGE_B__ = imcomplement(IMAGE_B_bw);%その反転した値
                elseif app.Adaptive.Value == 1                    
                    IMAGE_B_bw= imbinarize(IMAGE_B,'adaptive');%Adaptiveな閾値で二値化する
                    IMAGE_B__ = imcomplement(IMAGE_B_bw);%その反転した値
                else
                    IMAGE_B_bw= imbinarize(IMAGE_B,app.UpperThresholdEditField.Value);%上側の閾値で二値化する
                    IMAGE_B__ = imbinarize(IMAGE_B,app.LowerThresholdEditField.Value);%上側の閾値で二値化する
                    IMAGE_B__ = imcomplement(IMAGE_B__);%下側の閾値で二値化した値を反転する。1⇒0　あるいは　0⇒1　となる。                    
                end            
                %
                if app.RButton.Value == 1
                    app.Image_bw = IMAGE_R_bw.*IMAGE_G__.*IMAGE_B__ ;%画素要素ごとの積を計算　⇒　赤色部分の抽出
                elseif app.GButton.Value == 1
                    app.Image_bw = IMAGE_G_bw.*IMAGE_B__.*IMAGE_R__ ;%画素要素ごとの積を計算　⇒　緑色部分の抽出
                elseif app.BButton.Value == 1
                    app.Image_bw = IMAGE_B_bw.*IMAGE_R__.*IMAGE_G__ ;%画素要素ごとの積を計算　⇒　青色部分の抽出     
                elseif app.WButton.Value == 1
                    app.Image_bw = IMAGE_R_bw.*IMAGE_G_bw.*IMAGE_B_bw ;%画素要素ごとの積を計算　⇒　白色部分の抽出
                else
                    app.Image_bw = IMAGE_R__.*IMAGE_G__.*IMAGE_B__ ;%画素要素ごとの積を計算　⇒　黒色部分の抽出
                end
            end
            figure(2);
            imshow(app.Image_bw);
        end
        
        %%%%%%%%% タイマー割り込み処理 %%%%%%%%%%%%%%%
        %サンプリング時間毎にコールされます。
        function results = mytimer_fun(app,obj,event)
            BinarizedImage(app);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ReadimageButton
        function ReadimageButtonPushed(app, event)
            app.Ethernet = 0;
            
            try
                [app.filename, app.pathname] = uigetfile({'*.jpg';'*.png';'*.bmp'}, 'Select a image file');%ターゲットの画像ファイルを選択
            catch
                return;
            end
            eval(['cd ',app.pathname]) ;%そのフォルダに移動
            IMAGE_name = strcat(app.pathname,app.filename);
            app.IMAGE_org = imread(IMAGE_name);%イメージをリードする
            figure(1);
            imshow(app.IMAGE_org) % 取得した生画像を表示
            
            %---------------------------------
            % 取得した画像のプロパティを表示
            %---------------------------------
            app.EditField.Value = IMAGE_name; % 取得した生画像のファイルパスを表示
            [y,x,z]=size(app.IMAGE_org); % 取得した生画像のサイズを表示
            app.WidthEditField_before.Value = x;
            app.HeightEditField_before.Value = y; 
            app.ChannelEditField_before.Value = z;
        end

        % Button pushed function: CalcurateCOGButton
        function CalcurateCOGButtonPushed(app, event)
            BinarizedimageButtonPushed(app, event); %オリジナル画像に対して二値化処理
            %---------------------------------
            % 変換した画像のプロパティを表示
            %---------------------------------
            [y,x,z]=size(app.Image_bw); % 取得した生画像のサイズを表示
            app.WidthEditField_after.Value = x;
            app.HeightEditField_after.Value = y; 
            app.ChannelEditField_after.Value = z;
            
            if app.ConnectedButton_8.Value == 1
                conn = [1 1 1; 1 1 1; 1 1 1];%二次元の連結性：8連結
            else
                conn = [0 1 0; 1 1 1; 0 1 0];%二次元の連結性:4連結
            end
            
            IMAGE_bw2= bwareafilt(logical(app.Image_bw),1,conn);%一番大きいオブジェクトを抽出します。
            states = regionprops(IMAGE_bw2,'centroid','MajorAxisLength','MinorAxisLength','Orientation');%一番大きいオブジェクトの重心位置を計算します。
            if length(states) ==0
                fprintf('\n 🔺カラーベースの形状検出を行いましたが、オブジェクトの重心の位置が見つかりませんでした。\n');
                return;%処理中断
            end
            if states.MajorAxisLength < 100 %小さな消しゴムのカスを誤検知してしまうためにこの処理を入れた。
                fprintf('\n 🔺カラーベースの形状検出を行いましたが、100ピクセル以上の長さのオブジェクトは検出されませんでした。\n');
                return;%処理中断
            end            
            %
            fprintf('\n ☞ イメージ内でカラーマッチングで検出されたオブジェクトの重心の位置は [%.1f %.1f] です。\n', states.Centroid(1),states.Centroid(2));
            fprintf('\n ☞ イメージ内でカラーマッチングで検出されたオブジェクトの姿勢は [%.1f] です。これはMATLABのregionprops関数で計算されたものです。\n', states.Orientation);
            app.estimated_COG = states.Centroid ;
            image_centroid = insertMarker(double(app.Image_bw),app.estimated_COG,'size',5);%複数の特徴点の座標平均値を重心位置とする。
            figure(3);
            imshow(image_centroid); %重心位置をグリーンで表示！ 
        end

        % Callback function
        function LowerThresholdEditFieldValueChanged(app, event)
            
        end

        % Value changed function: Slider
        function SliderValueChanged(app, event)
            value = app.Slider.Value;
            if value < app.UpperThresholdEditField.Value % 下の閾値が上の閾値より小さいとき
                app.LowerThresholdEditField.Value = value;
                fprintf('\n Changed \n');
            else
                %app.LowerThresholdEditField.Value = value
                fprintf('\n ▲Not changed \n');
                fprintf('\n Upper Threshold >= Lower Threshold でなければなりません。\n');
                pause(1);
            end        
        end

        % Value changed function: Slider_2
        function Slider_2ValueChanged(app, event)
            value = app.Slider_2.Value;
            %value = event.Value;
            %value = app.UpperThresholdEditField.Value;
            if value >= app.LowerThresholdEditField.Value
                app.UpperThresholdEditField.Value = value;
                %app.Slider.Value = value;
                fprintf('\n Changed \n');
                %pause(1);
            else
                fprintf('\n ▲Not changed \n');
                fprintf('\n Upper Threshold >= Lower Threshold でなければなりません。\n');
                %pause(1);
            end   
        end

        % Button pushed function: BinarizedimageButton
        function BinarizedimageButtonPushed(app, event)
            if app.Ethernet == 0  %すでに自動チェックモード(Timer ON)のときは、app.Ethernetが1になっている。
               app.update_timer = timer('Period', app.PeriodsEditField.Value,...
               'ExecutionMode', 'fixedSpacing', ...
               'TasksToExecute', Inf, ... 
               'TimerFcn', @app.mytimer_fun); %コールバック
               start(app.update_timer);            
               
               app.Ethernet = 1;
            else
               stop(app.update_timer); %タイマー割り込みStop！
               delete(app.update_timer);
               app.Ethernet = 0;
               fprintf('\n ⇒　タイマー終了しました。 \n');
            end
        end

        % Button pushed function: ColorMapButton
        function ColorMapButtonPushed(app, event)
           C = zeros(1, 11, 3);
           if app.RButton.Value == 1
               C(:,:,1) = [.0 .1 .2 .3 .4 .5 .6 .7 .8 .9 1];
           elseif app.GButton.Value == 1
               C(:,:,2) = [.0 .1 .2 .3 .4 .5 .6 .7 .8 .9 1];
           elseif app.BButton.Value == 1
               C(:,:,3) = [.0 .1 .2 .3 .4 .5 .6 .7 .8 .9 1];
           end
           image(C);
           ax = gca;
           ax.XAxis.Visible = 'off'; %x軸を非表示
           ax.YAxis.Visible = 'off'; %y軸を非表示
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ReadimageButton = uibutton(app.UIFigure, 'push');
            app.ReadimageButton.ButtonPushedFcn = createCallbackFcn(app, @ReadimageButtonPushed, true);
            app.ReadimageButton.Text = 'Read image';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.CalcurateCOGButton = uibutton(app.UIFigure, 'push');
            app.CalcurateCOGButton.ButtonPushedFcn = createCallbackFcn(app, @CalcurateCOGButtonPushed, true);
            app.CalcurateCOGButton.Text = 'Calcurate COG';
            app.ConnectivitiesButton = uibuttongroup(app.UIFigure);
            app.ConnectivitiesButton.Title = '2D Connectivities';
            app.ConnectedButton_8 = uiradiobutton(app.ConnectivitiesButton);
            app.ConnectedButton_8.Text = '8-Connected';
            app.ConnectedButton_8.Value = true;
            app.ConnectedButton_4 = uiradiobutton(app.ConnectivitiesButton);
            app.ConnectedButton_4.Text = '4-Connected';
            app.Threshold_Type = uibuttongroup(app.UIFigure);
            app.Threshold_Type.Title = 'Threshold Type';
            app.Otsu = uiradiobutton(app.Threshold_Type);
            app.Otsu.Text = 'Otsu';
            app.Adaptive = uiradiobutton(app.Threshold_Type);
            app.Adaptive.Text = 'Adaptive';
            app.Two_Thresh = uiradiobutton(app.Threshold_Type);
            app.Two_Thresh.Text = '2-Thresh';
            app.Two_Thresh.Value = true;
            app.WidthEditFieldLabel = uilabel(app.UIFigure);
            app.WidthEditFieldLabel.Text = 'Width';
            app.WidthEditField_before = uieditfield(app.UIFigure, 'numeric');
            app.HeightEditFieldLabel = uilabel(app.UIFigure);
            app.HeightEditFieldLabel.Text = 'Height';
            app.HeightEditField_before = uieditfield(app.UIFigure, 'numeric');
            app.ChannelEditFieldLabel = uilabel(app.UIFigure);
            app.ChannelEditFieldLabel.Text = 'Channel';
            app.ChannelEditField_before = uieditfield(app.UIFigure, 'numeric');
            app.ConvertBeforePropertyLabel = uilabel(app.UIFigure);
            app.ConvertBeforePropertyLabel.Text = 'Convert Before Property';
            app.ConvertAfterPropertyLabel = uilabel(app.UIFigure);
            app.ConvertAfterPropertyLabel.Text = 'Convert Before Property';
            app.WidthEditFieldLabel_2 = uilabel(app.UIFigure);
            app.WidthEditFieldLabel_2.Text = 'Width';
            app.WidthEditField_after = uieditfield(app.UIFigure, 'numeric');
            app.HeightEditFieldLabel_2 = uilabel(app.UIFigure);
            app.HeightEditFieldLabel_2.Text = 'Height';
            app.HeightEditField_after = uieditfield(app.UIFigure, 'numeric');
            app.ChannelEditFieldLabel_2 = uilabel(app.UIFigure);
            app.ChannelEditFieldLabel_2.Text = 'Channel';
            app.ChannelEditField_after = uieditfield(app.UIFigure, 'numeric');
            app.BinaryTypeButtonGroup = uibuttongroup(app.UIFigure);
            app.BinaryTypeButtonGroup.Title = 'Binary Type';
            app.ExistingButton = uiradiobutton(app.BinaryTypeButtonGroup);
            app.ExistingButton.Text = 'Existing';
            app.OriginalButton = uiradiobutton(app.BinaryTypeButtonGroup);
            app.OriginalButton.Text = 'Original';
            app.OriginalButton.Value = true;
            app.PickUpColorButtonGroup = uibuttongroup(app.UIFigure);
            app.PickUpColorButtonGroup.Title = 'PickUpColor';
            app.RButton = uiradiobutton(app.PickUpColorButtonGroup);
            app.RButton.Text = 'R';
            app.GButton = uiradiobutton(app.PickUpColorButtonGroup);
            app.GButton.Text = 'G';
            app.BButton = uiradiobutton(app.PickUpColorButtonGroup);
            app.BButton.Text = 'B';
            app.WButton = uiradiobutton(app.PickUpColorButtonGroup);
            app.WButton.Text = 'W';
            app.BkButton = uiradiobutton(app.PickUpColorButtonGroup);
            app.BkButton.Text = 'Bk';
            app.BkButton.Value = true;
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [0 1];
            app.Slider.ValueChangedFcn = createCallbackFcn(app, @SliderValueChanged, true);
            app.Slider.Value = 0.1;
            app.LowerThresholdEditFieldLabel = uilabel(app.UIFigure);
            app.LowerThresholdEditFieldLabel.Text = {'Lower'; 'Threshold'};
            app.Slider_2 = uislider(app.UIFigure);
            app.Slider_2.Limits = [0 1];
            app.Slider_2.ValueChangedFcn = createCallbackFcn(app, @Slider_2ValueChanged, true);
            app.Slider_2.Value = 0.8;
            app.UpperThresholdEditFieldLabel = uilabel(app.UIFigure);
            app.UpperThresholdEditFieldLabel.Text = {'Upper'; 'Threshold'};
            app.LowerThresholdEditField = uieditfield(app.UIFigure, 'numeric');
            app.LowerThresholdEditField.Value = 0.1;
            app.UpperThresholdEditField = uieditfield(app.UIFigure, 'numeric');
            app.UpperThresholdEditField.Value = 0.8;
            app.BinarizedimageButton = uibutton(app.UIFigure, 'push');
            app.BinarizedimageButton.ButtonPushedFcn = createCallbackFcn(app, @BinarizedimageButtonPushed, true);
            app.BinarizedimageButton.Text = 'Binarized image';
            app.PriodsLabel = uilabel(app.UIFigure);
            app.PriodsLabel.Text = 'Priod [s]';
            app.PeriodsEditField = uieditfield(app.UIFigure, 'numeric');
            app.PeriodsEditField.Value = 1;
            app.ColorMapButton = uibutton(app.UIFigure, 'push');
            app.ColorMapButton.ButtonPushedFcn = createCallbackFcn(app, @ColorMapButtonPushed, true);
            app.ColorMapButton.Text = 'Color Map';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Image_Test_0309

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end